classdef Population < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AAEditField_2                  matlab.ui.control.NumericEditField
        AAEditField_2Label             matlab.ui.control.Label
        ABEditField_2                  matlab.ui.control.NumericEditField
        ABEditField_2Label             matlab.ui.control.Label
        BBEditField_2                  matlab.ui.control.NumericEditField
        BBEditField_2Label             matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        FixedAlleleLabel               matlab.ui.control.Label
        Fitnesswfrom0to1Label          matlab.ui.control.Label
        PhenotypicDesiredGenerationEditField  matlab.ui.control.NumericEditField
        PhenotypicDesiredGenerationEditFieldLabel  matlab.ui.control.Label
        ChanceofBtoAMutationSlider     matlab.ui.control.Slider
        ChanceofBtoAMutationLabel      matlab.ui.control.Label
        ChanceofAtoBMutationSlider     matlab.ui.control.Slider
        ChanceofAtoBMutationLabel      matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        NumberofGenerationsEditField   matlab.ui.control.NumericEditField
        NumberofGenerationsEditFieldLabel  matlab.ui.control.Label
        AllelefixedatgenerationLabel   matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        CloseSimulationButton          matlab.ui.control.Button
        FrequencyofAAlleleSlider       matlab.ui.control.Slider
        FrequencyofAAlleleSliderLabel  matlab.ui.control.Label
        PopulationSizeSlider           matlab.ui.control.Slider
        PopulationSizeSliderLabel      matlab.ui.control.Label
        RunSimulationButton            matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)


% Store the inputs entered by the user (some inputs are taken from the sliders or fields
% in the user interface 
            N = app.PopulationSizeSlider.Value; %Population size
            p(1) = app.FrequencyofAAlleleSlider.Value; %Frequency of allele A 
            q(1) = 1 - p(1); %Calculate allele frequency of allele B
            
            w_AA = app.AAEditField_2.Value; %Fitness of AA genotype
            w_BB = app.BBEditField_2.Value; %Fitness of BB genotype
            w_AB = app.ABEditField_2.Value; %Fitness of AB genotype
            
            forwardMutation= app.ChanceofAtoBMutationSlider.Value; %Rate of mutation from A -> B
            backwardMutation= app.ChanceofBtoAMutationSlider.Value; %Rate of mutation from B -> A

            G = app.NumberofGenerationsEditField.Value; %Number of generations that the simulaton will run

            t = 1; %Initialise generation counter
            n = 1; %Initialise the population size counter

while t<=G %Run a for loop for the generations
   %% Build population
    
   w_bar = p(t)^2*(w_AA) + 2*p(t)*q(t)*(w_AB) + q(t)^2*(w_BB); %Calculate the mean fitness
%Calculate the number of individuals of each genotypes, accounting for their relative fitness (need to round to get a whole number of individuals)
   
   AA(t) = round(N*(p(t)^2)*(w_AA/w_bar));
   BB(t) = round(N*(q(t)^2)*(w_BB/w_bar));
   AB(t) = round(N*(2*p(t)*q(t))*(w_AB/w_bar));

   %Create the chromosomes corresponding to the number of individuals for
   %each genotype. 1 indicates allele A and 0 indicates allele B.
   for j = 1:AA(t)
       chromosomes(:,:,j) = [1;1];
   end
   for j = AA(t) + 1:AA(t) + BB(t)
       chromosomes(:,:,j) = [0;0];
   end
   for j = AA(t) + BB(t) + 1: AA(t) + BB(t) + AB(t)
       chromosomes(:,:,j) = [1;0];
   end
   
   %% Selecting parents and producing offspring
   while n <= N 
       invalidMatch = true;
       
       while invalidMatch == true
           parentSelect = rand; %Random number to select the parent
           parent1 = floor(N*parentSelect) ;%First individual
           parentSelect = rand;
           parent2 = floor(N*parentSelect); %Second individual
           
           %Check that the parents are not the same because self-mating is
           %not allowed in the simulation
           if parent1 == 0 || parent2 == 0 %There is no 0th parent so this choice is not valid
               invalidMatch = true;
           elseif parent1 == parent2
               invalidMatch = true;
           elseif parent1 ~= parent2
               invalidMatch = false;
           end
       end
   
   % Producing the offspring
   offspringSelect1 = rand ;%Random number for gamete1 selection 
   offspringSelect2 = rand;
   
   %Pick chromosome for each gamete 
   if offspringSelect1 < 0.5
       gamete1 = chromosomes(1,1,parent1);
   elseif offspringSelect1 > 0.5
       gamete1 = chromosomes(2,1,parent1);
   end

   if offspringSelect2 < 0.5
       gamete2 = chromosomes(1,1,parent2);
   elseif offspringSelect2 > 0.5
         gamete2 = chromosomes(2,1,parent2);
   end

   %Do a mutation test to determine whether the the alleles will mutate 
   mutationSelect = rand;
   
   if gamete1 == 1 %If gamete 1 is allele A then we must consider the forward mutation rate (A -> B)
       mutate = mutationSelect < forwardMutation; %A mutation occurs if the random number is less than the mutation rate
       if mutate == 1
           gamete1 = 0;
       end
   elseif gamete1 == 0 %If gamete 1 is allele a then we must consider the backward mutation rate (A <- B)
       mutate = mutationSelect < backwardMutation; %A mutation occurs if the random number is less than the mutation rate
      
       if mutate == 1
           gamete1 = 1;
       end
   end
   if gamete2 == 1 %If gamete 2 is allele A then we must consider the forward mutation rate (A -> B)
       mutate = mutationSelect < forwardMutation;

      if mutate == 1
           gamete2 = 0;
       end
   elseif gamete2 == 0 %If gamete 2 is allele a then we must consider the backward mutation rate (A <- B)
       mutate = mutationSelect < backwardMutation;
      
       if mutate == 1
           gamete2 = 1;
       end
   end

   offspring(:,:,n) = [gamete1;gamete2]; %offspring is the combination of each gamete
   n = n + 1; %Increase n each time an offspring is produced.
   end

%% Calculate new frequencies of the alleles in the population. Store this in vectors.
pcount = find(offspring); %Finds the 1s i.e allele A
pcount = numel(pcount);
p(t+1) = pcount/(2*N);
q(t+1)= 1 - p(t+1);

n = 1 ;%Reset value of n
t = t + 1; %Increase the generation counter
end
%% outputs presentation
t= [0:G]; %Create a vector of the generations

% Determine which allele (if any) has fixed i.e frequency = 1
fixedAllele = find(p==1,1)
app.Label_3.Text = 'A'

if isempty(fixedAllele) == 1
   fixedAllele = find(q==1,1)
   app.Label_3.Text = 'B'
   if isempty(fixedAllele) ==1
       app.Label_3.Text = 'N/A'
   end
end

%Determine generation the allele fixed
fixed = find(p==1,1)
if isempty(fixed) == 1
   fixed = find(q==1,1)
   
end
app.Label.Text = num2str(fixed);


% plot frequencies of each Allele
cla(app.UIAxes)
hold(app.UIAxes, 'on')

p1=plot(app.UIAxes,p,'LineWidth',1, 'Marker','.','color','r');
p2=plot(app.UIAxes,q,'LineWidth',1, 'Marker','.','color','b');
axis(app.UIAxes, 'tight');

legend(app.UIAxes,{'Frequency of Allele A','Frequency of Allele B'},'Location','northeast');

hold(app.UIAxes, 'off')

% plot each phenotype

%take the inserted value and round it 
r= app.PhenotypicDesiredGenerationEditField.Value
r = round(r)
% the number random position is equal to the array length 
% each random position will represent a small dot on the graph 

%AA phentype will be red , AB will be green, and BB will be blue
y1(1)=3
for i= 1:AA(r+1)
    y1(i)= randi([10 1000])
end
y2(1)=3
for j= 1:AB(r+1)
    y2(j)= randi([10 1000])
end


y3(1)=3
for k= 1:BB(r+1)
    y3(k)= randi([10 1000])
end
cla(app.UIAxes2)
hold(app.UIAxes2, 'on')

plot(app.UIAxes2, y1, '.','MarkerSize',10,'color','r')
plot(app.UIAxes2, y2, '.','MarkerSize',10,'color','g')
plot(app.UIAxes2, y3, '.','MarkerSize',10,'color','b')
legend(app.UIAxes2,{'AA Allele','AB Allele','BB Allele'},'Location','northeast');

hold(app.UIAxes2, 'off')

        end

        % Value changing function: PopulationSizeSlider
        function PopulationSizeSliderValueChanging(app, event)
           

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           %delete(app)
            
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Button pushed function: CloseSimulationButton
        function CloseSimulationButtonPushed(app, event)
            closereq
        end

        % Callback function: not associated with a component
        function ShowAlleleinspecificgenerationButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Change in Alleles Frequency over time')
            xlabel(app.UIAxes, 'Generations')
            ylabel(app.UIAxes, 'Frequency of Alleles')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1000];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Phenotype Distribution')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.PopulationSizeSliderLabel = uilabel(app.UIFigure);
            app.PopulationSizeSliderLabel.Text = 'Population Size';
            app.PopulationSizeSlider = uislider(app.UIFigure);
            app.PopulationSizeSlider.Limits = [10 1000];
            app.PopulationSizeSlider.MajorTicks = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180 190 200 210 220 230 240 250 260 270 280 290 300 310 320 330 340 350 360 370 380 390 400 410 420 430 440 450 460 470 480 490 500 510 520 530 540 550 560 570 580 590 600 610 620 630 640 650 660 670 680 690 700 710 720 730 740 750 760 770 780 790 800 810 820 830 840 850 860 870 880 890 900 910 920 930 940 950 960 970 980 990 1000];
            app.PopulationSizeSlider.MajorTickLabels = {'10', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '', '1000'};
            app.PopulationSizeSlider.ValueChangingFcn = createCallbackFcn(app, @PopulationSizeSliderValueChanging, true);
            app.PopulationSizeSlider.MinorTicks = [];
            app.PopulationSizeSlider.Value = 10;
            app.FrequencyofAAlleleSliderLabel = uilabel(app.UIFigure);
            app.FrequencyofAAlleleSliderLabel.Text = 'Frequency of A Allele';
            app.FrequencyofAAlleleSlider = uislider(app.UIFigure);
            app.FrequencyofAAlleleSlider.Limits = [0 1];
            app.FrequencyofAAlleleSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.FrequencyofAAlleleSlider.MajorTickLabels = {'0', '0.2', '0.4', '0.6', '0.8', '1'};
            app.FrequencyofAAlleleSlider.MinorTicks = [0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.CloseSimulationButton = uibutton(app.UIFigure, 'push');
            app.CloseSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @CloseSimulationButtonPushed, true);
            app.CloseSimulationButton.Text = 'Close Simulation';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.AllelefixedatgenerationLabel = uilabel(app.UIFigure);
            app.AllelefixedatgenerationLabel.Text = 'Allele fixed at generation:';
            app.NumberofGenerationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofGenerationsEditFieldLabel.Text = 'Number of Generations';
            app.NumberofGenerationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofGenerationsEditField.Limits = [0 1000];
            app.NumberofGenerationsEditField.Value = 1000;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.ChanceofAtoBMutationLabel = uilabel(app.UIFigure);
            app.ChanceofAtoBMutationLabel.Text = 'Chance of A to B Mutation';
            app.ChanceofAtoBMutationSlider = uislider(app.UIFigure);
            app.ChanceofAtoBMutationSlider.Limits = [0 0.01];
            app.ChanceofBtoAMutationLabel = uilabel(app.UIFigure);
            app.ChanceofBtoAMutationLabel.Text = 'Chance of B to A Mutation';
            app.ChanceofBtoAMutationSlider = uislider(app.UIFigure);
            app.ChanceofBtoAMutationSlider.Limits = [0 0.01];
            app.PhenotypicDesiredGenerationEditFieldLabel = uilabel(app.UIFigure);
            app.PhenotypicDesiredGenerationEditFieldLabel.Text = 'Phenotypic Desired Generation';
            app.PhenotypicDesiredGenerationEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhenotypicDesiredGenerationEditField.Limits = [0 999];
            app.Fitnesswfrom0to1Label = uilabel(app.UIFigure);
            app.Fitnesswfrom0to1Label.Text = 'Fitness (w) (# from 0 to 1)';
            app.FixedAlleleLabel = uilabel(app.UIFigure);
            app.FixedAlleleLabel.Text = 'Fixed Allele:';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.BBEditField_2Label = uilabel(app.UIFigure);
            app.BBEditField_2Label.Text = 'BB';
            app.BBEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.BBEditField_2.Value = 1;
            app.ABEditField_2Label = uilabel(app.UIFigure);
            app.ABEditField_2Label.Text = 'AB';
            app.ABEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ABEditField_2.Value = 1;
            app.AAEditField_2Label = uilabel(app.UIFigure);
            app.AAEditField_2Label.Text = 'AA';
            app.AAEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.AAEditField_2.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Population

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end